classdef appDibujar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ESFERADERIEMANNLabel  matlab.ui.control.Label
        ReiniciarButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            fig1 = app.UIAxes;
            fig2 = app.UIAxes_2;
            axis(fig1,[-10 10 -10 10]);
            
            %Plot esfera de Riemann en fig2
            [X,Y,Z] = sphere;
            hSurface=surf(fig2,X,Y,Z);
            axis equal;
            set(hSurface,'FaceColor','none','FaceAlpha',0.5,'FaceLighting',...
                'gouraud','EdgeColor','k','LineStyle','--','LineWidth',0.01)
            
            hold(fig2, "on");
            
            %Puntos iniciales
            [x1,y1] = ginput(2);
            plot(fig1, x1,y1,'Color','b');
            
            %Guardar puntos
            hold(fig1, 'on');
            vectx = [x1(1) x1(2)];
            vecty = [y1(1) y1(2)];
            
            %Parametrizar
            t = 0:0.001:1;
            param =@(t,x1,y1,x2,y2) [x1 y1] + t.*[x2-x1 y2-y1];
            
            %Plot parametrizacion en la esfera de Riemann
            vectparamx = [];
            vectparamy = [];
            for tt=1:length(t) 
               xx2 = param(t(tt),x1(1),y1(1),x1(2),y1(2));
               vectparamx = horzcat(vectparamx, xx2(1));
               vectparamy = horzcat(vectparamy, xx2(2)); 
            end
            coord = f_coord_riemann(vectparamx+1i*vectparamy);
            plot3(fig2,coord(1,:),coord(2,:),coord(3,:),'.','Color','b','LineWidth',1);
            
            
            % Realizar el mismo proceso con los puntos que se quiera
            
            [x2,y2] = ginput(1);
            while isempty(x2) == false
                
                plot(fig1,[vectx(end) x2],[vecty(end) y2],'b');
                axis(fig1,[-10 10 -10 10]);
                
                vectparamx = [];
                vectparamy = [];
                for tl = 1:length(t)
                    vectparam = param(t(tl),vectx(end), vecty(end),x2,y2);
                    vectparamx = horzcat(vectparamx, vectparam(1));
                    vectparamy = horzcat(vectparamy, vectparam(2));        
                end

                coord = f_coord_riemann(vectparamx+1i*vectparamy);
                plot3(fig2,coord(1,:),coord(2,:),coord(3,:),'.','Color','b','LineWidth',1);
                hold(fig2,'on');
                vectx = horzcat(vectx, x2);
                vecty = horzcat(vecty, y2);
            
                [x2, y2] = ginput(1);
            end
            
            hold(fig1,'off');
            hold(fig2,'off');
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
            fig1 = app.UIAxes;
            fig2 = app.UIAxes_2;
            cla(fig1);
            axis(fig1,[-10 10 -10 10]);
            
            [X,Y,Z] = sphere;
            hSurface=surf(fig2,X,Y,Z);
            axis equal;
            set(hSurface,'FaceColor','none','FaceAlpha',0.5,'FaceLighting',...
                'gouraud','EdgeColor','k','LineStyle','--','LineWidth',0.01)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESFERADERIEMANNLabel = uilabel(app.UIFigure);
            app.ESFERADERIEMANNLabel.Text = 'ESFERA DE RIEMANN';
            app.ReiniciarButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dibujar')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Riemann')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDibujar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end